classdef final_1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        ClicktocontinueButton           matlab.ui.control.Button
        DevelopersLabel                 matlab.ui.control.Label
        DevelpoersMdNagibMahfuzMdHamidurRahmanEmran20061662006167Label  matlab.ui.control.Label
        SimulationAppLabel              matlab.ui.control.Label
        DOUBLEPENDULUMLabel             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MethodTab                       matlab.ui.container.Tab
        ChooseaSolvingMethodLabel       matlab.ui.control.Label
        ImprovedEulersMethodButton_2    matlab.ui.control.Button
        EulersMethodButton_2            matlab.ui.control.Button
        IOPanelEulerTab                 matlab.ui.container.Tab
        PhaseSpaceButton                matlab.ui.control.Button
        MomentumvstimeButton            matlab.ui.control.Button
        Theta1vsTheta2Button            matlab.ui.control.Button
        EnegryvstimeButton              matlab.ui.control.Button
        OmegavstimeThetavstimeButton    matlab.ui.control.Button
        OutputLabel                     matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        L2EditField                     matlab.ui.control.NumericEditField
        L2EditFieldLabel                matlab.ui.control.Label
        L1EditField                     matlab.ui.control.NumericEditField
        L1EditFieldLabel                matlab.ui.control.Label
        m2EditField                     matlab.ui.control.NumericEditField
        m2EditFieldLabel                matlab.ui.control.Label
        m1EditField                     matlab.ui.control.NumericEditField
        m1EditFieldLabel                matlab.ui.control.Label
        theta2Knob                      matlab.ui.control.Knob
        theta2KnobLabel                 matlab.ui.control.Label
        theta1Knob                      matlab.ui.control.Knob
        theta1KnobLabel                 matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        IOPanelImprovedEulerTab_2       matlab.ui.container.Tab
        PhaseSpaceButton_2              matlab.ui.control.Button
        MomentumvstimeButton_2          matlab.ui.control.Button
        Theta1vsTheta2Button_2          matlab.ui.control.Button
        EnegryvstimeButton_2            matlab.ui.control.Button
        OmegavstimeThetavstimeButton_2  matlab.ui.control.Button
        OutputLabel_2                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        TimeEditField_2                 matlab.ui.control.NumericEditField
        TimeEditField_2Label            matlab.ui.control.Label
        L2EditField_2                   matlab.ui.control.NumericEditField
        L2EditField_2Label              matlab.ui.control.Label
        L1EditField_2                   matlab.ui.control.NumericEditField
        L1EditField_2Label              matlab.ui.control.Label
        m2EditField_2                   matlab.ui.control.NumericEditField
        m2EditField_2Label              matlab.ui.control.Label
        m1EditField_2                   matlab.ui.control.NumericEditField
        m1EditField_2Label              matlab.ui.control.Label
        theta2Knob_2                    matlab.ui.control.Knob
        theta2Knob_2Label               matlab.ui.control.Label
        theta1Knob_2                    matlab.ui.control.Knob
        theta1Knob_2Label               matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        OutputGraphsTab                 matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        OmegavstimeThetavstimeTab       matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        PhaseSpaceTab                   matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        Theta1vsTheta2Tab               matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        EnegryvstimeTab                 matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        MomentumvstimeTab               matlab.ui.container.Tab
        UIAxes12                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClicktocontinueButton
        function ClicktocontinueButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MethodTab;
        end

        % Callback function
        function EulersMethodButtonPushed(app, event)

        end

        % Callback function
        function StartButtonPushed(app, event)
           
        end

        % Value changed function: theta1Knob
        function theta1KnobValueChanged(app, event)
            value = app.theta1Knob.Value;
            app.EditField.Value=value;
        end

        % Value changing function: theta1Knob
        function theta1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value=changingValue;
        end

        % Value changing function: theta2Knob
        function theta2KnobValueChanging(app, event)
            changingValue2 = event.Value;
            app.EditField_2.Value=changingValue2; 
        end

        % Button pushed function: EulersMethodButton_2
        function EulersMethodButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.IOPanelEulerTab;
        end

        % Button pushed function: ImprovedEulersMethodButton_2
        function ImprovedEulersMethodButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.IOPanelImprovedEulerTab_2;
        end

        % Button pushed function: OmegavstimeThetavstimeButton
        function OmegavstimeThetavstimeButtonPushed(app, event)
             % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField.Value; % length of pendulum 1 (m)
L2 = app.L2EditField.Value; % length of pendulum 2 (m)
m1 = app.m1EditField.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_2.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);
x1 = zeros(1, N);
x2 = zeros(1, N);
y1 = zeros(1, N);
y2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Euler's method
for i = 1:N-1
 
    % Update angular velocity and angle
    omega1(i+1) = omega1(i) + alpha1(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    omega2(i+1) = omega2(i) + alpha2(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;


end
% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.OmegavstimeThetavstimeTab;


for i = 2:length(t)
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes,"x-position (m)");
        ylabel(app.UIAxes,"y-positoin (m)");
        title(app.UIAxes,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes,'on')
        plot(app.UIAxes,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes,'off')
        pause(0.00001);


 % Plot angular displacement vs time

plot(app.UIAxes2, t(1:i), theta1(1:i), 'r-', t(1:i), theta2(1:i), 'b-');


 % Plot angular velocity vs time

plot(app.UIAxes3, t(1:i), omega1(1:i), 'r-', t(1:i), omega2(1:i), 'b-');


 end
end
        end

        % Button pushed function: PhaseSpaceButton
        function PhaseSpaceButtonPushed(app, event)
            % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField.Value; % length of pendulum 1 (m)
L2 = app.L2EditField.Value; % length of pendulum 2 (m)
m1 = app.m1EditField.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_2.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Euler's method
for i = 1:N-1
   
    % Update angular velocity and angle
    omega1(i+1) = omega1(i) + alpha1(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    omega2(i+1) = omega2(i) + alpha2(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;


end
% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.PhaseSpaceTab;


for i = 2:length(t)
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes4,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes4,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes4,"x-position (m)y");
        ylabel(app.UIAxes4,"y-positoin (m)");
        title(app.UIAxes4,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes4,'on')
        plot(app.UIAxes4,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes4,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes4,'off')
        pause(0.00001);


 % Plot angular displacement vs time

plot(app.UIAxes5, theta1(1:i), omega1(1:i),'r-');


plot(app.UIAxes6, theta2(1:i), omega2(1:i),'b-')


 end
end 

        end

        % Button pushed function: Theta1vsTheta2Button
        function Theta1vsTheta2ButtonPushed(app, event)
             % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField.Value; % length of pendulum 1 (m)
L2 = app.L2EditField.Value; % length of pendulum 2 (m)
m1 = app.m1EditField.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_2.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Euler's method
for i = 1:N-1
   
    % Update angular velocity and angle
    omega1(i+1) = omega1(i) + alpha1(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    omega2(i+1) = omega2(i) + alpha2(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;


end
% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.Theta1vsTheta2Tab;


for i = 2:length(t)
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes7,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes7,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes7,"x-position (m)y");
        ylabel(app.UIAxes7,"y-positoin (m)");
        title(app.UIAxes7,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes7,'on')
        plot(app.UIAxes7,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes7,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes7,'off')
        pause(0.00001);


 % Plot angular displacement vs time

plot(app.UIAxes8, theta1(1:i), theta2(1:i),'r-');


 end
end 
        end

        % Button pushed function: EnegryvstimeButton
        function EnegryvstimeButtonPushed(app, event)
            % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField.Value; % length of pendulum 1 (m)
L2 = app.L2EditField.Value; % length of pendulum 2 (m)
m1 = app.m1EditField.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_2.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Euler's method
for i = 1:N-1
    % Calculate angular acceleration
    
    
    % Update angular velocity and angle
    omega1(i+1) = omega1(i) + alpha1(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    omega2(i+1) = omega2(i) + alpha2(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;


end
% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);


%set up energy

for i=2:N-1
     % Plot energy vs time


K(i)=.5*(m1+m2)*L1^2*(omega1(i)^2)+.5*(m2)*L2^2*(omega2(i)^2)+m2*L1*L2*omega1(i)*omega2(i)*cos(theta1(i)-theta2(i));
U(i)=-(m1+m2)*g*L1*cos(theta1(i))-m2*g*L2*cos(theta2(i));
E(i) = K (i)+ U(i);
end

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.EnegryvstimeTab;


for i = 2:length(t)-1

    plot(app.UIAxes10, t(1:i),E(1:i), 'r-',t(1:i),K(1:i), 'b-',t(1:i),U(1:i), 'g-');
    legend(app.UIAxes10,'E', 'K','U');


 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes9,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes9,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes9,"x-position (m)y");
        ylabel(app.UIAxes9,"y-positoin (m)");
        title(app.UIAxes9,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes9,'on')
        plot(app.UIAxes9,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes9,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes9,'off')
        pause(0.00001);


 end
end 


        end

        % Button pushed function: MomentumvstimeButton
        function MomentumvstimeButtonPushed(app, event)

% Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField.Value; % length of pendulum 1 (m)
L2 = app.L2EditField.Value; % length of pendulum 2 (m)
m1 = app.m1EditField.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_2.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Euler's method
for i = 1:N-1
    % Calculate angular acceleration
    
    
    % Update angular velocity and angle
    omega1(i+1) = omega1(i) + alpha1(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    omega2(i+1) = omega2(i) + alpha2(theta1(i),theta2(i),omega1(i),omega2(i))*dt;
    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;


end
% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);


            % Calculate momentum 


p1 = m1*L1^2*omega1 + m2*L1^2*omega1 + m2*L1*L2*cos(theta1-theta2).*(omega2);
 p2 = m2*L2^2*omega2 + m2*L1*L2*cos(theta1-theta2).*(omega1);


app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.MomentumvstimeTab;


for i = 2:length(t)-1

   
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes11,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes11,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes11,"x-position (m)y");
        ylabel(app.UIAxes11,"y-positoin (m)");
        title(app.UIAxes11,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes11,'on')
        plot(app.UIAxes11,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes11,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes11,'off')
        pause(0.00001);

plot(app.UIAxes12, t(1:i),p1(1:i), 'r-',t(1:i),p2(1:i), 'b-');
legend(app.UIAxes12,'\p1', '\p2');


 end
end 


        end

        % Button pushed function: OmegavstimeThetavstimeButton_2
        function OmegavstimeThetavstimeButton_2Pushed(app, event)
            % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField_2.Value; % length of pendulum 1 (m)
L2 = app.L2EditField_2.Value; % length of pendulum 2 (m)
m1 = app.m1EditField_2.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField_2.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField_3.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_4.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField_2.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);
x1 = zeros(1, N);
x2 = zeros(1, N);
y1 = zeros(1, N);
y2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Improved Euler's method
 for i = 1:N-1
    
    % Update angular velocity and angle

    omega1(i+1)=omega1(i)+(dt/2)*(alpha1(theta1(i),theta2(i),omega1(i),omega2(i))+alpha1(theta1(i+1),theta2(i+1),(omega1(i)+alpha1(theta1(i),theta2(i),omega1(i),omega2(i)).*dt),omega2(i+1)));

     omega2(i+1)=omega2(i)+(dt/2)*(alpha2(theta1(i),theta2(i),omega1(i),omega2(i))+alpha2(theta1(i+1),theta2(i+1),omega1(i+1),(omega2(i)+alpha2(theta1(i),theta2(i),omega1(i),omega2(i)).*dt)));

    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;
end

% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.OmegavstimeThetavstimeTab;


for i = 2:length(t)
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes,"x-position (m)");
        ylabel(app.UIAxes,"y-positoin (m)");
        title(app.UIAxes,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes,'on')
        plot(app.UIAxes,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes,'off')
        pause(0.00001);


 % Plot angular displacement vs time

plot(app.UIAxes2, t(1:i), theta1(1:i), 'r-', t(1:i), theta2(1:i), 'b-');


    % Plot angular velocity vs time

plot(app.UIAxes3, t(1:i), omega1(1:i), 'r-', t(1:i), omega2(1:i), 'b-');


 end
end
        end

        % Value changed function: theta1Knob_2
        function theta1Knob_2ValueChanged(app, event)
            value = app.theta1Knob_2.Value;
            app.EditField_3.Value=value;
            
        end

        % Value changing function: theta1Knob_2
        function theta1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value=changingValue;
        end

        % Value changed function: theta2Knob_2
        function theta2Knob_2ValueChanged(app, event)
            value = app.theta2Knob_2.Value;
            app.EditField_4.Value=value;
        end

        % Value changing function: theta2Knob_2
        function theta2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value=changingValue;
        end

        % Button pushed function: PhaseSpaceButton_2
        function PhaseSpaceButton_2Pushed(app, event)
            % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField_2.Value; % length of pendulum 1 (m)
L2 = app.L2EditField_2.Value; % length of pendulum 2 (m)
m1 = app.m1EditField_2.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField_2.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField_3.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_4.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField_2.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);
x1 = zeros(1, N);
x2 = zeros(1, N);
y1 = zeros(1, N);
y2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Improved Euler's method
 for i = 1:N-1
    
    % Update angular velocity and angle

    omega1(i+1)=omega1(i)+(dt/2)*(alpha1(theta1(i),theta2(i),omega1(i),omega2(i))+alpha1(theta1(i+1),theta2(i+1),(omega1(i)+alpha1(theta1(i),theta2(i),omega1(i),omega2(i)).*dt),omega2(i+1)));

     omega2(i+1)=omega2(i)+(dt/2)*(alpha2(theta1(i),theta2(i),omega1(i),omega2(i))+alpha2(theta1(i+1),theta2(i+1),omega1(i+1),(omega2(i)+alpha2(theta1(i),theta2(i),omega1(i),omega2(i)).*dt)));

    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;
end

% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.PhaseSpaceTab;


for i = 2:length(t)
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes4,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes4,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes4,"x-position (m)y");
        ylabel(app.UIAxes4,"y-positoin (m)");
        title(app.UIAxes4,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes4,'on')
        plot(app.UIAxes4,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes4,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes4,'off')
        pause(0.00001);


 % Plot angular displacement vs time

plot(app.UIAxes5, theta1(1:i), omega1(1:i),'r-');


plot(app.UIAxes6, theta2(1:i), omega2(1:i),'b-')


 end
end 
        end

        % Button pushed function: Theta1vsTheta2Button_2
        function Theta1vsTheta2Button_2Pushed(app, event)
             % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField_2.Value; % length of pendulum 1 (m)
L2 = app.L2EditField_2.Value; % length of pendulum 2 (m)
m1 = app.m1EditField_2.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField_2.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField_3.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_4.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField_2.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);
x1 = zeros(1, N);
x2 = zeros(1, N);
y1 = zeros(1, N);
y2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Improved Euler's method
 for i = 1:N-1
    
    % Update angular velocity and angle

    omega1(i+1)=omega1(i)+(dt/2)*(alpha1(theta1(i),theta2(i),omega1(i),omega2(i))+alpha1(theta1(i+1),theta2(i+1),(omega1(i)+alpha1(theta1(i),theta2(i),omega1(i),omega2(i)).*dt),omega2(i+1)));

     omega2(i+1)=omega2(i)+(dt/2)*(alpha2(theta1(i),theta2(i),omega1(i),omega2(i))+alpha2(theta1(i+1),theta2(i+1),omega1(i+1),(omega2(i)+alpha2(theta1(i),theta2(i),omega1(i),omega2(i)).*dt)));

    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;
end

% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.Theta1vsTheta2Tab;


for i = 2:length(t)
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes7,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes7,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes7,"x-position (m)y");
        ylabel(app.UIAxes7,"y-positoin (m)");
        title(app.UIAxes7,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes7,'on')
        plot(app.UIAxes7,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes7,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes7,'off')
        pause(0.00001);


 % Plot angular displacement1 vs angular displacement2

plot(app.UIAxes8, theta1(1:i), theta2(1:i),'r-');


 end
end 


        end

        % Button pushed function: EnegryvstimeButton_2
        function EnegryvstimeButton_2Pushed(app, event)
            % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField_2.Value; % length of pendulum 1 (m)
L2 = app.L2EditField_2.Value; % length of pendulum 2 (m)
m1 = app.m1EditField_2.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField_2.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField_3.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_4.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField_2.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);
x1 = zeros(1, N);
x2 = zeros(1, N);
y1 = zeros(1, N);
y2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Improved Euler's method
 for i = 1:N-1
    
    % Update angular velocity and angle

    omega1(i+1)=omega1(i)+(dt/2)*(alpha1(theta1(i),theta2(i),omega1(i),omega2(i))+alpha1(theta1(i+1),theta2(i+1),(omega1(i)+alpha1(theta1(i),theta2(i),omega1(i),omega2(i)).*dt),omega2(i+1)));

     omega2(i+1)=omega2(i)+(dt/2)*(alpha2(theta1(i),theta2(i),omega1(i),omega2(i))+alpha2(theta1(i+1),theta2(i+1),omega1(i+1),(omega2(i)+alpha2(theta1(i),theta2(i),omega1(i),omega2(i)).*dt)));

    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;
end

% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);


%set up energy

for i=2:N-1
     % Plot energy vs time

 K(i)=.5*(m1+m2)*L1^2*(omega1(i)^2)+.5*(m2)*L2^2*(omega2(i)^2)+m2*L1*L2*omega1(i)*omega2(i)*cos(theta1(i)-theta2(i));
U(i)=-(m1+m2)*g*L1*cos(theta1(i))-m2*g*L2*cos(theta2(i));
E(i) = K (i)+ U(i);


end

app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.EnegryvstimeTab;


for i = 2:length(t)-1

    plot(app.UIAxes10, t(1:i),E(1:i), 'r-',t(1:i),K(1:i), 'b-',t(1:i),U(1:i), 'g-');
    legend(app.UIAxes10,'E', 'K','U');

 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes9,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes9,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes9,"x-position (m)y");
        ylabel(app.UIAxes9,"y-positoin (m)");
        title(app.UIAxes9,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes9,'on')
        plot(app.UIAxes9,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes9,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes9,'off')
        pause(0.00001);


 end
end 


        end

        % Button pushed function: MomentumvstimeButton_2
        function MomentumvstimeButton_2Pushed(app, event)
             % Define constants and initial conditions
g = 9.81; % acceleration due to gravity (m/s^2)
L1 = app.L1EditField_2.Value; % length of pendulum 1 (m)
L2 = app.L2EditField_2.Value; % length of pendulum 2 (m)
m1 = app.m1EditField_2.Value; % mass of pendulum 1 (kg)
m2 = app.m2EditField_2.Value; % mass of pendulum 2 (kg)
thetad1=app.EditField_3.Value;
theta1_0 = thetad1*(pi/180); % initial angle of pendulum 1 (rad)
thetad2=app.EditField_4.Value;
theta2_0 =thetad2*(pi/180);% initial angle of pendulum 2 (rad)
omega1_0 = 0; % initial angular velocity of pendulum 1 (rad/s)
omega2_0 = 0; % initial angular velocity of pendulum 2 (rad/s)
t_start = 0; % start time (s)
t_end = app.TimeEditField_2.Value; % end time (s)
dt = .01; % time step (s)

% Initialize arrays to store results
t = t_start:dt:t_end;
N = length(t);
theta1 = zeros(1, N);
theta2 = zeros(1, N);
omega1 = zeros(1, N);
omega2 = zeros(1, N);
x1 = zeros(1, N);
x2 = zeros(1, N);
y1 = zeros(1, N);
y2 = zeros(1, N);

% Set initial conditions
theta1(1) = theta1_0;
theta2(1) = theta2_0;
omega1(1) = omega1_0;
omega2(1) = omega2_0;


alpha1 =@(theta1,theta2,omega1,omega2) (-g*(2*m1+m2)*sin(theta1) - m2*g*sin(theta1-2*theta2) - 2*sin(theta1-theta2)*m2*(omega2^2*L2+omega1^2*L1*cos(theta1-theta2))) / (L1*(2*m1+m2-m2*cos(2*theta1-2*theta2)));
 alpha2 = @(theta1,theta2,omega1,omega2)(2*sin(theta1-theta2)*(omega1^2*L1*(m1+m2)+g*(m1+m2)*cos(theta1)+omega2^2*L2*m2*cos(theta1-theta2))) / (L2*(2*m1+m2-m2*cos(2*theta1-2*theta2)));

% Solve using Improved Euler's method
 for i = 1:N-1
    
    % Update angular velocity and angle

    omega1(i+1)=omega1(i)+(dt/2)*(alpha1(theta1(i),theta2(i),omega1(i),omega2(i))+alpha1(theta1(i+1),theta2(i+1),(omega1(i)+alpha1(theta1(i),theta2(i),omega1(i),omega2(i)).*dt),omega2(i+1)));

     omega2(i+1)=omega2(i)+(dt/2)*(alpha2(theta1(i),theta2(i),omega1(i),omega2(i))+alpha2(theta1(i+1),theta2(i+1),omega1(i+1),(omega2(i)+alpha2(theta1(i),theta2(i),omega1(i),omega2(i)).*dt)));

    theta1(i+1) = theta1(i) + omega1(i+1)*dt;
    theta2(i+1) = theta2(i) + omega2(i+1)*dt;
end

% Set up animation
x1 = L1*sin(theta1);
y1 = -L1*cos(theta1);
x2 = x1 + L2*sin(theta2);
y2 = y1 - L2*cos(theta2);


            % Calculate momentum 

p1 = m1*L1^2*omega1 + m2*L1^2*omega1 + m2*L1*L2*cos(theta1-theta2).*(omega2);
 p2 = m2*L2^2*omega2 + m2*L1*L2*cos(theta1-theta2).*(omega1);


app.TabGroup.SelectedTab=app.OutputGraphsTab;
 app.TabGroup2.SelectedTab=app.MomentumvstimeTab;


for i = 2:length(t)-1

   
 if (rem(i,0.02/dt) == 0) || (i==length(t))
        plot(app.UIAxes11,[0, x1(i), x2(i)], [0, y1(i), y2(i)], '-o');
        axis(app.UIAxes11,[-(L1+L2+1) (L1+L2+1) -(L1+L2+1) (L1+L2+1)]);
        
        xlabel(app.UIAxes11,"x-position (m)y");
        ylabel(app.UIAxes11,"y-positoin (m)");
        title(app.UIAxes11,['Plot of double pendulum system at time = ', num2str(t(i), '% 1.2f'), 's']); %dynamically changes time displayed in the title
         hold(app.UIAxes11,'on')
        plot(app.UIAxes11,x1(1:i), y1(1:i), 'r');
        plot(app.UIAxes11,x2(1:i), y2(1:i), 'g');
         hold(app.UIAxes11,'off')
        pause(0.00001);

plot(app.UIAxes12, t(1:i),p1(1:i), 'r-',t(1:i),p2(1:i), 'b-');
legend(app.UIAxes12,'\p1', '\p2');


 end
end 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.DOUBLEPENDULUMLabel = uilabel(app.HomeTab);
            app.DOUBLEPENDULUMLabel.Text = 'DOUBLE PENDULUM                   ';
            app.SimulationAppLabel = uilabel(app.HomeTab);
            app.SimulationAppLabel.Text = 'Simulation App';
            app.DevelpoersMdNagibMahfuzMdHamidurRahmanEmran20061662006167Label = uilabel(app.HomeTab);
            app.DevelpoersMdNagibMahfuzMdHamidurRahmanEmran20061662006167Label.Text = {''; ''; 'Md. Nagib Mahfuz     Md.Hamidur Rahman '; 'EEE,BUET                   EEE,BUET'; 'ID:2006166                ID:2006167'};
            app.DevelopersLabel = uilabel(app.HomeTab);
            app.DevelopersLabel.Text = 'Developers:';
            app.ClicktocontinueButton = uibutton(app.HomeTab, 'push');
            app.ClicktocontinueButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktocontinueButtonPushed, true);
            app.ClicktocontinueButton.Text = 'Click to continue';
            app.MethodTab = uitab(app.TabGroup);
            app.MethodTab.Title = 'Method ';
            app.EulersMethodButton_2 = uibutton(app.MethodTab, 'push');
            app.EulersMethodButton_2.ButtonPushedFcn = createCallbackFcn(app, @EulersMethodButton_2Pushed, true);
            app.EulersMethodButton_2.Text = '1. Euler''s Method';
            app.ImprovedEulersMethodButton_2 = uibutton(app.MethodTab, 'push');
            app.ImprovedEulersMethodButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImprovedEulersMethodButton_2Pushed, true);
            app.ImprovedEulersMethodButton_2.Text = '2. Improved Euler''s Method';
            app.ChooseaSolvingMethodLabel = uilabel(app.MethodTab);
            app.ChooseaSolvingMethodLabel.Text = 'Choose a Solving Method :';
            app.IOPanelEulerTab = uitab(app.TabGroup);
            app.IOPanelEulerTab.Title = 'I/O Panel(Euler)';
            app.Image2 = uiimage(app.IOPanelEulerTab);
            app.theta1KnobLabel = uilabel(app.IOPanelEulerTab);
            app.theta1KnobLabel.Text = 'theta1';
            app.theta1Knob = uiknob(app.IOPanelEulerTab, 'continuous');
            app.theta1Knob.Limits = [-180 180];
            app.theta1Knob.ValueChangedFcn = createCallbackFcn(app, @theta1KnobValueChanged, true);
            app.theta1Knob.ValueChangingFcn = createCallbackFcn(app, @theta1KnobValueChanging, true);
            app.theta2KnobLabel = uilabel(app.IOPanelEulerTab);
            app.theta2KnobLabel.Text = 'theta2';
            app.theta2Knob = uiknob(app.IOPanelEulerTab, 'continuous');
            app.theta2Knob.Limits = [-180 180];
            app.theta2Knob.ValueChangingFcn = createCallbackFcn(app, @theta2KnobValueChanging, true);
            app.m1EditFieldLabel = uilabel(app.IOPanelEulerTab);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1EditField = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.m2EditFieldLabel = uilabel(app.IOPanelEulerTab);
            app.m2EditFieldLabel.Text = 'm2';
            app.m2EditField = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.L1EditFieldLabel = uilabel(app.IOPanelEulerTab);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.L2EditFieldLabel = uilabel(app.IOPanelEulerTab);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.IOPanelEulerTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.EditField = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.EditField_2 = uieditfield(app.IOPanelEulerTab, 'numeric');
            app.OutputLabel = uilabel(app.IOPanelEulerTab);
            app.OutputLabel.Text = 'Output';
            app.OmegavstimeThetavstimeButton = uibutton(app.IOPanelEulerTab, 'push');
            app.OmegavstimeThetavstimeButton.ButtonPushedFcn = createCallbackFcn(app, @OmegavstimeThetavstimeButtonPushed, true);
            app.OmegavstimeThetavstimeButton.Text = 'Omega vs time & Theta vs time';
            app.EnegryvstimeButton = uibutton(app.IOPanelEulerTab, 'push');
            app.EnegryvstimeButton.ButtonPushedFcn = createCallbackFcn(app, @EnegryvstimeButtonPushed, true);
            app.EnegryvstimeButton.Text = 'Enegry vs time';
            app.Theta1vsTheta2Button = uibutton(app.IOPanelEulerTab, 'push');
            app.Theta1vsTheta2Button.ButtonPushedFcn = createCallbackFcn(app, @Theta1vsTheta2ButtonPushed, true);
            app.Theta1vsTheta2Button.Text = 'Theta1 vs Theta2';
            app.MomentumvstimeButton = uibutton(app.IOPanelEulerTab, 'push');
            app.MomentumvstimeButton.ButtonPushedFcn = createCallbackFcn(app, @MomentumvstimeButtonPushed, true);
            app.MomentumvstimeButton.Text = 'Momentum vs time';
            app.PhaseSpaceButton = uibutton(app.IOPanelEulerTab, 'push');
            app.PhaseSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseSpaceButtonPushed, true);
            app.PhaseSpaceButton.Text = 'Phase Space';
            app.IOPanelImprovedEulerTab_2 = uitab(app.TabGroup);
            app.IOPanelImprovedEulerTab_2.Title = 'I/O Panel(Improved Euler)';
            app.Image2_2 = uiimage(app.IOPanelImprovedEulerTab_2);
            app.theta1Knob_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.theta1Knob_2Label.Text = 'theta1';
            app.theta1Knob_2 = uiknob(app.IOPanelImprovedEulerTab_2, 'continuous');
            app.theta1Knob_2.Limits = [-180 180];
            app.theta1Knob_2.ValueChangedFcn = createCallbackFcn(app, @theta1Knob_2ValueChanged, true);
            app.theta1Knob_2.ValueChangingFcn = createCallbackFcn(app, @theta1Knob_2ValueChanging, true);
            app.theta2Knob_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.theta2Knob_2Label.Text = 'theta2';
            app.theta2Knob_2 = uiknob(app.IOPanelImprovedEulerTab_2, 'continuous');
            app.theta2Knob_2.Limits = [-180 180];
            app.theta2Knob_2.ValueChangedFcn = createCallbackFcn(app, @theta2Knob_2ValueChanged, true);
            app.theta2Knob_2.ValueChangingFcn = createCallbackFcn(app, @theta2Knob_2ValueChanging, true);
            app.m1EditField_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.m1EditField_2Label.Text = 'm1';
            app.m1EditField_2 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.m2EditField_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.m2EditField_2Label.Text = 'm2';
            app.m2EditField_2 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.L1EditField_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.L1EditField_2Label.Text = 'L1';
            app.L1EditField_2 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.L2EditField_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.L2EditField_2Label.Text = 'L2';
            app.L2EditField_2 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.TimeEditField_2Label = uilabel(app.IOPanelImprovedEulerTab_2);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.EditField_3 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.EditField_4 = uieditfield(app.IOPanelImprovedEulerTab_2, 'numeric');
            app.OutputLabel_2 = uilabel(app.IOPanelImprovedEulerTab_2);
            app.OutputLabel_2.Text = 'Output';
            app.OmegavstimeThetavstimeButton_2 = uibutton(app.IOPanelImprovedEulerTab_2, 'push');
            app.OmegavstimeThetavstimeButton_2.ButtonPushedFcn = createCallbackFcn(app, @OmegavstimeThetavstimeButton_2Pushed, true);
            app.OmegavstimeThetavstimeButton_2.Text = 'Omega vs time & Theta vs time';
            app.EnegryvstimeButton_2 = uibutton(app.IOPanelImprovedEulerTab_2, 'push');
            app.EnegryvstimeButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnegryvstimeButton_2Pushed, true);
            app.EnegryvstimeButton_2.Text = 'Enegry vs time';
            app.Theta1vsTheta2Button_2 = uibutton(app.IOPanelImprovedEulerTab_2, 'push');
            app.Theta1vsTheta2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Theta1vsTheta2Button_2Pushed, true);
            app.Theta1vsTheta2Button_2.Text = 'Theta1 vs Theta2';
            app.MomentumvstimeButton_2 = uibutton(app.IOPanelImprovedEulerTab_2, 'push');
            app.MomentumvstimeButton_2.ButtonPushedFcn = createCallbackFcn(app, @MomentumvstimeButton_2Pushed, true);
            app.MomentumvstimeButton_2.Text = 'Momentum vs time';
            app.PhaseSpaceButton_2 = uibutton(app.IOPanelImprovedEulerTab_2, 'push');
            app.PhaseSpaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @PhaseSpaceButton_2Pushed, true);
            app.PhaseSpaceButton_2.Text = 'Phase Space';
            app.OutputGraphsTab = uitab(app.TabGroup);
            app.OutputGraphsTab.Title = 'Output Graphs';
            app.TabGroup2 = uitabgroup(app.OutputGraphsTab);
            app.OmegavstimeThetavstimeTab = uitab(app.TabGroup2);
            app.OmegavstimeThetavstimeTab.Title = 'Omega vs time & Theta vs time';
            app.UIAxes = uiaxes(app.OmegavstimeThetavstimeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.OmegavstimeThetavstimeTab);
            title(app.UIAxes2, ' θ vs t')
            xlabel(app.UIAxes2, 't(sec)')
            ylabel(app.UIAxes2, ' θ(rad)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.OmegavstimeThetavstimeTab);
            title(app.UIAxes3, 'w vs t')
            xlabel(app.UIAxes3, 't(sec)')
            ylabel(app.UIAxes3, '(w (rad/sec))')
            zlabel(app.UIAxes3, 'Z')
            app.PhaseSpaceTab = uitab(app.TabGroup2);
            app.PhaseSpaceTab.Title = 'Phase Space';
            app.UIAxes4 = uiaxes(app.PhaseSpaceTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.PhaseSpaceTab);
            title(app.UIAxes5, 'w1 vs θ1')
            xlabel(app.UIAxes5, 'θ1(rad)')
            ylabel(app.UIAxes5, 'w1(rad/sec)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.PhaseSpaceTab);
            title(app.UIAxes6, 'w2 vs θ2')
            xlabel(app.UIAxes6, 'θ2(rad)')
            ylabel(app.UIAxes6, 'w2(rad/sec)')
            zlabel(app.UIAxes6, 'Z')
            app.Theta1vsTheta2Tab = uitab(app.TabGroup2);
            app.Theta1vsTheta2Tab.Title = 'Theta1 vs Theta2';
            app.UIAxes7 = uiaxes(app.Theta1vsTheta2Tab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.Theta1vsTheta2Tab);
            title(app.UIAxes8, 'θ1 vs θ2')
            xlabel(app.UIAxes8, 'θ1(rad)')
            ylabel(app.UIAxes8, 'θ2(rad)')
            zlabel(app.UIAxes8, 'Z')
            app.EnegryvstimeTab = uitab(app.TabGroup2);
            app.EnegryvstimeTab.Title = 'Enegry vs time';
            app.UIAxes9 = uiaxes(app.EnegryvstimeTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.EnegryvstimeTab);
            title(app.UIAxes10, 'E vs t')
            xlabel(app.UIAxes10, 't(sec)')
            ylabel(app.UIAxes10, 'E(j)')
            zlabel(app.UIAxes10, 'Z')
            app.MomentumvstimeTab = uitab(app.TabGroup2);
            app.MomentumvstimeTab.Title = 'Momentum vs time';
            app.UIAxes11 = uiaxes(app.MomentumvstimeTab);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.MomentumvstimeTab);
            title(app.UIAxes12, 'L vs t')
            xlabel(app.UIAxes12, 't(sec)')
            ylabel(app.UIAxes12, 'L(Kgm^2sec^-1)')
            zlabel(app.UIAxes12, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end